classdef app1PLOTTERb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        We3GiversLabel               matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        ImportAnalysisTab            matlab.ui.container.Tab
        AnalyzeRespCheckBox          matlab.ui.control.CheckBox
        EntervectorLabel             matlab.ui.control.Label
        BadSubjsEditField            matlab.ui.control.EditField
        BadSubjsEditFieldLabel       matlab.ui.control.Label
        CantplotLamp                 matlab.ui.control.Lamp
        SaveStatsCheckBox            matlab.ui.control.CheckBox
        PlotsinseparatewindowsPanel  matlab.ui.container.Panel
        PlotinQuartersDropDown       matlab.ui.control.DropDown
        PlotinQuartersDropDownLabel  matlab.ui.control.Label
        SaveCheckBox_5               matlab.ui.control.CheckBox
        SaveCheckBox_4               matlab.ui.control.CheckBox
        SaveCheckBox_3               matlab.ui.control.CheckBox
        SaveCheckBox_2               matlab.ui.control.CheckBox
        PlotsinglesubjectsCheckBox   matlab.ui.control.CheckBox
        SaveCheckBox                 matlab.ui.control.CheckBox
        PlotAveragesCheckBox         matlab.ui.control.CheckBox
        PlotcorrVASVASCheckBox       matlab.ui.control.CheckBox
        PlotSingCouplesCheckBox      matlab.ui.control.CheckBox
        TransformNormalizePanel      matlab.ui.container.Panel
        NormalizationDropDown        matlab.ui.control.DropDown
        NormalizationDropDownLabel   matlab.ui.control.Label
        NormECGCheckBox              matlab.ui.control.CheckBox
        EDAthresholdDropDown         matlab.ui.control.DropDown
        EDAthresholdDropDownLabel    matlab.ui.control.Label
        NormVASCheckBox              matlab.ui.control.CheckBox
        NormEDACheckBox              matlab.ui.control.CheckBox
        AnalysisWindowPanel          matlab.ui.container.Panel
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        OKButton_2                   matlab.ui.control.Button
        PlotsTab                     matlab.ui.container.Tab
        FCheckBox                    matlab.ui.control.CheckBox
        MCheckBox                    matlab.ui.control.CheckBox
        OverallCheckBox              matlab.ui.control.CheckBox
        CorrelatewithDropDown        matlab.ui.control.DropDown
        InteractionsPanel            matlab.ui.container.Panel
        StrangerEyeCheckBox          matlab.ui.control.CheckBox
        StrangerCrossCheckBox_2      matlab.ui.control.CheckBox
        PartnerEyeCheckBox           matlab.ui.control.CheckBox
        PartnerCrossCheckBox         matlab.ui.control.CheckBox
        MainPanel                    matlab.ui.container.Panel
        StrangerCheckBox_2           matlab.ui.control.CheckBox
        PartnerCheckBox_2            matlab.ui.control.CheckBox
        EyeCheckBox                  matlab.ui.control.CheckBox
        CrossCheckBox                matlab.ui.control.CheckBox
        OKButton                     matlab.ui.control.Button
        MeasureDropDown              matlab.ui.control.DropDown
        MeasureDropDown_4Label       matlab.ui.control.Label
        FactorDropDown               matlab.ui.control.DropDown
        FactorDropDownLabel          matlab.ui.control.Label
        Factor2DropDown              matlab.ui.control.DropDown
        Factor2DropDownLabel         matlab.ui.control.Label
        XaxisDropDown                matlab.ui.control.DropDown
        XaxisDropDownLabel           matlab.ui.control.Label
        SeparategraphsDropDown       matlab.ui.control.DropDown
        SeparategraphsDropDownLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        Tables                       matlab.ui.container.Tab
        UITable_2                    matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        t1 % First table
        t2 % Second table
        pp % Description
        toCorr % Measure to correlate with main measure
        corrM % Genders and checkboxes to split for correlations
        corrF
        corrOverall
        corrPartn
        corrStrang
        corrEye
        corrCross
        corrPartnEye
        corrPartnCross
        corrStrangEye
        corrStrangCross
        c1 % all other various check boxes
        c2
        c3
        c4
        c5
        c6
        c7
        c8
        c9
        c10
        c11
        c12
        c13
        c14
        c15
        c16
        c17
        c18
        c19
        c20
        c21
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.corrM = app.MCheckBox.Value;
            app.corrF = app.FCheckBox.Value;
            app.corrOverall = app.OverallCheckBox.Value;
            app.corrPartn = app.PartnerCheckBox_2.Value;
            app.corrStrang = app.StrangerCheckBox_2.Value;
            app.corrEye = app.EyeCheckBox.Value;
            app.corrCross = app.CrossCheckBox.Value;
            app.corrPartnEye = app.PartnerEyeCheckBox.Value;
            app.corrPartnCross = app.PartnerCrossCheckBox.Value;
            app.corrStrangEye = app.StrangerEyeCheckBox.Value;
            app.corrStrangCross = app.StrangerCrossCheckBox_2.Value;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

%%%%%%%%%%%%%%%%%%%%%%%%%%  IMPORT  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         
        % Import first table (for all plots)
            
        toimport = app.MeasureDropDown.Value;
            if contains(toimport, 'Stai')
                app.UITable.Data = evalin('base', 'stai');
                app.UITable.ColumnName = {'tratto'	'stato_baseline'	'stato_P'	'stato_S'	'deltaP'	'deltaS'};
            else
                app.UITable.Data = evalin('base', toimport);
                app.UITable.ColumnName = {'Gender' 'StrangerCross' 'StrangerEye' 'PartnerCross' 'PartnerEye'};
            end
        
            app.t1 = app.UITable;
          
        % Import second table (only for correlations)
        
        app.toCorr = app.CorrelatewithDropDown.Value;
            if contains(app.toCorr, 'Stai')
                app.UITable_2.Data = evalin('base', 'stai');
                app.UITable_2.ColumnName = {'tratto'	'stato_baseline'	'stato_P'	'stato_S'	'deltaP'	'deltaS'};
            elseif contains(app.toCorr, 'DAS')
                app.UITable_2.Data = evalin('base', 'DAS');
                app.UITable_2.ColumnName = {'Consensus'	'AffExpr'	'Satisfaction'	'Cohesion'	'Tot'};
            else
                app.UITable_2.Data = evalin('base', app.toCorr);
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ')
                        app.UITable_2.ColumnName = {'Gender' 'StrangerCross' 'StrangerEye' 'PartnerCross' 'PartnerEye'};
                    else
                    end
            end
        
            app.t2 = app.UITable_2;
            
           
%%%%%%%%%%%%%%%%%%  PLOT MAIN EFFECTS  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


fact = app.FactorDropDown.Value;
        switch fact
            
            case 'Other'
                y1 = (app.t1.Data{:,2:3});
                y1err = std(mean(y1,2))/sqrt(size(y1,1));
                y1 = mean(mean(y1));
                
                y2 = (app.t1.Data{:,4:5});
                y2err = std(mean(y2,2))/sqrt(size(y2,1));
                y2 = mean(mean(y2));
                
                x = 1:2;
                y = [y1 y2];
                yerr = [y1err y2err];
                
                errorbar(app.UIAxes,x,y,yerr,'Color','k');
                app.UIAxes.XTick = 1:2;
                app.UIAxes.XTickLabel = {'Stranger' 'Partner'};
                app.UIAxes.XLim = [0.5 2.5];
                
            
            case 'Sguardo'
                y1 = (app.t1.Data{:,[2 4]});
                y1err = std(mean(y1,2))/sqrt(size(y1,1));
                y1 = mean(mean(y1));
                
                y2 = (app.t1.Data{:,[3 5]});
                y2err = std(mean(y2,2))/sqrt(size(y2,1));
                y2 = mean(mean(y2));
                
                x = 1:2;
                y = [y1 y2];
                yerr = [y1err y2err];

                errorbar(app.UIAxes,x,y,yerr,'Color','k');
                app.UIAxes.XTick = 1:2;
                app.UIAxes.XTickLabel = {'Cross' 'Eye'};
                app.UIAxes.XLim = [0.5 2.5];                            
                
            case 'Gender'
                isM = contains(app.t1.Data.Gender, 'M');
                y1 = app.t1.Data{isM,2:end};
                y1err = std(mean(y1,2))/sqrt(size(y1,1));
                y1 = mean(mean(y1));
                
                isF = contains(app.t1.Data.Gender, 'F');
                y2 = app.t1.Data{isF,2:end};
                y2err = std(mean(y2,2))/sqrt(size(y2,1));
                y2 = mean(mean(y2));
                
                x = 1:2;
                y = [y1 y2];
                yerr = [y1err y2err];

                errorbar(app.UIAxes,x,y,yerr,'Color','k');
                app.UIAxes.XTick = 1:2;
                app.UIAxes.XTickLabel = {'Males' 'Females'};
                app.UIAxes.XLim = [0.5 2.5];


        end
app.UIAxes.XLabel.String = fact;         
app.UIAxes.YLabel.String = toimport;      


%%%%%%%%%%%%%%%%%%  PLOT DOUBLE INTERACTIONS  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

xa = app.XaxisDropDown.Value;
sl = app.Factor2DropDown.Value;

x = 1:2;

y1 = app.t1.Data{:,2}; % Stranger Cross
y1err = std(y1)/sqrt(length(y1));

y2 = app.t1.Data{:,3}; % Stranger Eye
y2err = std(y2)/sqrt(length(y2));

y3 = app.t1.Data{:,4}; % Partner Cross
y3err = std(y3)/sqrt(length(y3));

y4 = app.t1.Data{:,5}; % Partner Eye
y4err = std(y4)/sqrt(length(y4));


%% Redefine measures for Genders
isM = contains(app.t1.Data.Gender, 'M');
y1a = y1(isM); y2a = y2(isM); y3a = y3(isM); y4a = y4(isM);
y1erra = std(y1a)/sqrt(length(y1a)); y2erra = std(y2a)/sqrt(length(y2a)); y3erra = std(y3a)/sqrt(length(y3a)); y4erra = std(y4a)/sqrt(length(y4a));

isF = contains(app.t1.Data.Gender, 'F');
y1b = y1(isF); y2b = y2(isF); y3b = y3(isF); y4b = y4(isF);
y1errb = std(y1b)/sqrt(length(y1b)); y2errb = std(y2b)/sqrt(length(y2b)); y3errb = std(y3b)/sqrt(length(y3b)); y4errb = std(y4b)/sqrt(length(y4b));


        switch xa
            case 'Other' % Asse X
                
                switch sl % separate lines
                    case 'Sguardo'
                            y = [mean(y1) mean(y3)];         % StrangerCross (left),  PartnerCross (right)
                            yerr = [y1err y3err];
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','k');                          

                            hold(app.UIAxes2, 'on')
                            
                            y = [mean(y2) mean(y4)];         % StrangerEye (left),  PartnerEye (right)
                            yerr = [y2err y4err];
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','r');
                            app.UIAxes2.XTick = 1:2;
                            app.UIAxes2.XTickLabel = {'Stranger' 'Partner'};
                            app.UIAxes2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes2, 'Cross', 'Eye','Location','best');
                            hold(app.UIAxes2, 'off')
                                 
                            
                    case 'Gender'
                        % Males
                            y = [mean(y1a) mean(y2a);      % Stranger_Cross_Male, Stranger_Eye_Male,
                                 mean(y3a) mean(y4a)];     % Partner_Cross_Male, Partner_Eye_Male    
                            y = mean(y,2)';             % mean by rows (i.e. spearately for Stranger and Partner)
                            yerr = [y1erra y2erra;
                                    y3erra y4erra];
                            yerr = mean(yerr,2)';
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','k');                          

                            hold(app.UIAxes2, 'on')
                            
                            % Females
                            y = [mean(y1b) mean(y2b);      % Stranger_Cross_Male, Stranger_Eye_Male,
                                 mean(y3b) mean(y4b)];     % Partner_Cross_Male, Partner_Eye_Male    
                            y = mean(y,2)';             % mean by rows (i.e. spearately for Stranger and Partner)
                            yerr = [y1errb y2errb;
                                    y3errb y4errb];
                            yerr = mean(yerr,2)';
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','r');                          

                            app.UIAxes2.XTick = 1:2;
                            app.UIAxes2.XTickLabel = {'Stranger' 'Partner'};
                            app.UIAxes2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes2, 'Males', 'Females','Location','best');
                            hold(app.UIAxes2, 'off')


                end


            case 'Sguardo' % Asse X
                
                switch sl % separate lines
                    case 'Other'
                            y = [mean(y1) mean(y2)];         % StrangerCross (left),  StrangerEye (right)
                            yerr = [y1err y2err];
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','k');                          

                            hold(app.UIAxes2, 'on')
                            
                            y = [mean(y3) mean(y4)];         % PartnerCross (left),  PartnerEye (right)
                            yerr = [y3err y4err];
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','r');
                            app.UIAxes2.XTick = 1:2;
                            app.UIAxes2.XTickLabel = {'Cross' 'Eye'};
                            app.UIAxes2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes2, 'Stranger', 'Partner','Location','best');
                            hold(app.UIAxes2, 'off')
                                 
                            
                    case 'Gender' 
                        % Males
                            y = [mean(y1a) mean(y2a);      % Stranger_Cross_Male, Stranger_Eye_Male,
                                 mean(y3a) mean(y4a)];     % Partner_Cross_Male, Partner_Eye_Male    
                            y = mean(y);             % mean by columns (i.e. spearately for Cross and Eye)
                            yerr = [y1erra y2erra;
                                    y3erra y4erra];
                            yerr = mean(yerr);
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','k');                          

                            hold(app.UIAxes2, 'on')
                            
                            % Females
                            y = [mean(y1b) mean(y2b);      % Stranger_Cross_Male, Stranger_Eye_Male,
                                 mean(y3b) mean(y4b)];     % Partner_Cross_Male, Partner_Eye_Male    
                            y = mean(y);             % mean by rows (i.e. spearately for Cross and Eye)
                            yerr = [y1errb y2errb;
                                    y3errb y4errb];
                            yerr = mean(yerr);
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','r');                          

                            app.UIAxes2.XTick = 1:2;
                            app.UIAxes2.XTickLabel = {'Cross' 'Eye'};
                            app.UIAxes2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes2, 'Males', 'Females','Location','best');
                            hold(app.UIAxes2, 'off')
                                                
                end        
        
                
            case 'Gender' % Asse X         
                
                switch sl 
                    case 'Other'
                       % Stranger
                            y = [mean(y1a) mean(y2a);      % Stranger_Cross_Male, Stranger_Eye_Male,
                                 mean(y1b) mean(y2b)];     % Stranger_Cross_Female, Stranger_Eye_Female    
                            y = mean(y,2)';             % mean by rows (i.e. spearately for Male and Female) in Stranger
                            yerr = [y1erra y2erra;
                                    y1errb y2errb];
                            yerr = mean(yerr,2)';
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','k');                          

                            hold(app.UIAxes2, 'on')
                            
                            % Partner        
                            y = [mean(y3a) mean(y4a);      % Partner_Cross_Male, Partner_Eye_Male,
                                 mean(y3b) mean(y4b)];     % Partner_Cross_Female, Partner_Eye_Female    
                            y = mean(y,2)';             % mean by rows (i.e. spearately for Male and Female) in Partner
                            yerr = [y3erra y4erra;
                                    y3errb y4errb];
                            yerr = mean(yerr,2)';
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','r');                          

                            app.UIAxes2.XTick = 1:2;
                            app.UIAxes2.XTickLabel = {'Males' 'Females'};
                            app.UIAxes2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes2, 'Stranger', 'Partner','Location','best');
                            hold(app.UIAxes2, 'off')     
                    
                    case 'Sguardo'
                       % Cross      
                            y = [mean(y1a) mean(y3a);      % Stranger_Cross_Male, Partner_Cross_Male,
                                 mean(y1b) mean(y3b)];     % Stranger_Cross_Female, Partner_Cross_Female    
                            y = mean(y,2)';             % mean by rows (i.e. spearately for Male and Female) in Cross
                            yerr = [y1erra y3erra;
                                    y1errb y3errb];
                            yerr = mean(yerr,2)';
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','k');                          

                            hold(app.UIAxes2, 'on')
                            
                            % Eye        
                            y = [mean(y2a) mean(y4a);      % Stranger_Eye_Male, Partner_Eye_Male,
                                 mean(y2b) mean(y4b)];     % Stranger_Eye_Female, Partner_Eye_Female    
                            y = mean(y,2)';             % mean by rows (i.e. spearately for Male and Female) in Eye
                            yerr = [y2erra y4erra;
                                    y2errb y4errb];
                            yerr = mean(yerr,2)';
                            
                            errorbar(app.UIAxes2,x,y,yerr,'Color','r');                          

                            app.UIAxes2.XTick = 1:2;
                            app.UIAxes2.XTickLabel = {'Males' 'Females'};
                            app.UIAxes2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes2, 'Cross', 'Eye','Location','best');
                            hold(app.UIAxes2, 'off')
                 
                end
        
        end


app.UIAxes2.XLabel.String = xa;
app.UIAxes2.YLabel.String = toimport;      


if string(xa) == string(sl)
    cla(app.UIAxes2);
    text(app.UIAxes2, 1.25, 0.5, 'Same factor chosen', 'FontSize', 13, 'FontWeight','bold','Color','r','HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
else
end


%%%%%%%%%%%%%%%%%%  PLOT TRIPLE INTERACTIONS  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%  


        switch app.SeparategraphsDropDown.Value
            case 'Other'
                
                % Stranger (Left Panel)
                
                        % Cross      
                            y = [mean(y1a) mean(y1b)];      % Stranger_Cross_Male, Stranger_Cross_Female
                            yerr = [y1erra y1errb];
                            
                            errorbar(app.UIAxes_2,x,y,yerr,'Color','k');

                            hold(app.UIAxes_2, 'on')
                            
                        % Eye        
                            y = [mean(y2a) mean(y2b)];      % Stranger_Eye_Male, Stranger_Eye_Female   
                            yerr = [y2erra y2errb];
                            
                            errorbar(app.UIAxes_2,x,y,yerr,'Color','r');
                            
                            app.UIAxes_2.XTick = 1:2;
                            app.UIAxes_2.XTickLabel = {'Males' 'Females'};
                            app.UIAxes_2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes_2, 'Cross', 'Eye','Location','best');
                            hold(app.UIAxes_2, 'off')
                            
                
                 % Partner (Right Panel)
                
                        % Cross      
                            y = [mean(y3a) mean(y3b)];      % Partner_Cross_Male, Partner_Cross_Female
                            yerr = [y3erra y3errb];
                            
                            errorbar(app.UIAxes_3,x,y,yerr,'Color','k');

                            hold(app.UIAxes_3, 'on')
                            
                        % Eye        
                            y = [mean(y4a) mean(y4b)];      % Partner_Eye_Male, Partner_Eye_Female   
                            yerr = [y4erra y4errb];
                            
                            errorbar(app.UIAxes_3,x,y,yerr,'Color','r');
                            
                            app.UIAxes_3.XTick = 1:2;
                            app.UIAxes_3.XTickLabel = {'Males' 'Females'};
                            app.UIAxes_3.XLim = [0.5 2.5]; 
                            legend(app.UIAxes_3, 'Cross', 'Eye','Location','best');
                            hold(app.UIAxes_3, 'off')
                            
                            app.UIAxes_2.Title.String = 'Stranger';
                            app.UIAxes_3.Title.String = 'Partner';
                            app.UIAxes_2.XLabel.String = 'Gender';
                            app.UIAxes_3.XLabel.String = 'Gender';
         
                            
            case 'Sguardo'
                
                % Cross (Left Panel)
                
                        % Stranger      
                            y = [mean(y1a) mean(y1b)];      % Stranger_Cross_Male, Stranger_Cross_Female
                            yerr = [y1erra y1errb];
                            
                            errorbar(app.UIAxes_2,x,y,yerr,'Color','k');

                            hold(app.UIAxes_2, 'on')
                            
                        % Partner        
                            y = [mean(y3a) mean(y3b)];      % Partner_Cross_Male, Partner_Cross_Female   
                            yerr = [y3erra y3errb];
                            
                            errorbar(app.UIAxes_2,x,y,yerr,'Color','r');
                            
                            app.UIAxes_2.XTick = 1:2;
                            app.UIAxes_2.XTickLabel = {'Males' 'Females'};
                            app.UIAxes_2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes_2, 'Stranger', 'Partner','Location','best');
                            hold(app.UIAxes_2, 'off')
                            
                
                 % Eye (Right Panel)
                
                        % Stranger      
                            y = [mean(y2a) mean(y2b)];      % Stranger_Eye_Male, Stranger_Eye_Female
                            yerr = [y2erra y2errb];
                            
                            errorbar(app.UIAxes_3,x,y,yerr,'Color','k');

                            hold(app.UIAxes_3, 'on')
                            
                        % Partner        
                            y = [mean(y4a) mean(y4b)];      % Partner_Eye_Male, Partner_Eye_Female   
                            yerr = [y4erra y4errb];
                            
                            errorbar(app.UIAxes_3,x,y,yerr,'Color','r');
                            
                            app.UIAxes_3.XTick = 1:2;
                            app.UIAxes_3.XTickLabel = {'Males' 'Females'};
                            app.UIAxes_3.XLim = [0.5 2.5]; 
                            legend(app.UIAxes_3, 'Stranger', 'Partner','Location','best');
                            hold(app.UIAxes_3, 'off')
                            
                            app.UIAxes_2.Title.String = 'Cross';
                            app.UIAxes_3.Title.String = 'Eye';
                            app.UIAxes_2.XLabel.String = 'Gender';
                            app.UIAxes_3.XLabel.String = 'Gender';
                               
            case 'Gender'
                
                % Males (Left Panel)
                
                        % Cross      
                            y = [mean(y1a) mean(y3a)];      % Stranger_Cross_Male, Partner_Cross_Male
                            yerr = [y1erra y3erra];
                            
                            errorbar(app.UIAxes_2,x,y,yerr,'Color','k');

                            hold(app.UIAxes_2, 'on')
                            
                        % Eye        
                            y = [mean(y2a) mean(y4a)];      % Stranger_Eye_Male, Partner_Eye_Male   
                            yerr = [y2erra y4erra];
                            
                            errorbar(app.UIAxes_2,x,y,yerr,'Color','r');
                            
                            app.UIAxes_2.XTick = 1:2;
                            app.UIAxes_2.XTickLabel = {'Stranger' 'Partner'};
                            app.UIAxes_2.XLim = [0.5 2.5]; 
                            legend(app.UIAxes_2, 'Cross', 'Eye','Location','best');
                            hold(app.UIAxes_2, 'off')
                            
                
                 % Females (Right Panel)
                
                        % Cross      
                            y = [mean(y1b) mean(y3b)];      % Stranger_Cross_Female, Partner_Cross_Female
                            yerr = [y1errb y3errb];
                            
                            errorbar(app.UIAxes_3,x,y,yerr,'Color','k');

                            hold(app.UIAxes_3, 'on')
                            
                        % Eye        
                            y = [mean(y2b) mean(y4b)];      % Stranger_Eye_Female, Partner_Eye_Female   
                            yerr = [y2errb y4errb];
                            
                            errorbar(app.UIAxes_3,x,y,yerr,'Color','r');
                            
                            app.UIAxes_3.XTick = 1:2;
                            app.UIAxes_3.XTickLabel = {'Stranger' 'Partner'};
                            app.UIAxes_3.XLim = [0.5 2.5]; 
                            legend(app.UIAxes_3, 'Cross', 'Eye','Location','best');
                            hold(app.UIAxes_3, 'off')
                            
                            app.UIAxes_2.Title.String = 'Males';
                            app.UIAxes_3.Title.String = 'Females';
                            app.UIAxes_2.XLabel.String = 'Other';
                            app.UIAxes_3.XLabel.String = 'Other';
                            
                            
        end


        end

        % Value changed function: FactorDropDown
        function FactorDropDownValueChanged(app, event)
        % plot Main Effect
      fact = app.FactorDropDown.Value;
        switch fact
            
            case 'Other'
                y1 = (app.t1.Data{:,2:3});
                y1err = std(mean(y1,2))/sqrt(size(y1,1));
                y1 = mean(mean(y1));
                
                y2 = (app.t1.Data{:,4:5});
                y2err = std(mean(y2,2))/sqrt(size(y2,1));
                y2 = mean(mean(y2));
                
                x = 1:2;
                y = [y1 y2];
                yerr = [y1err y2err];
                
                errorbar(app.UIAxes,x,y,yerr,'Color','k');
                app.UIAxes.XTick = 1:2;
                app.UIAxes.XTickLabel = {'Stranger' 'Partner'};
                app.UIAxes.XLim = [0.5 2.5];
            
            case 'Sguardo'
                y1 = (app.t1.Data{:,[2 4]});
                y1err = std(mean(y1,2))/sqrt(size(y1,1));
                y1 = mean(mean(y1));
                
                y2 = (app.t1.Data{:,[3 5]});
                y2err = std(mean(y2,2))/sqrt(size(y2,1));
                y2 = mean(mean(y2));
                
                x = 1:2;
                y = [y1 y2];
                yerr = [y1err y2err];

                errorbar(app.UIAxes,x,y,yerr,'Color','k');
                app.UIAxes.XTick = 1:2;
                app.UIAxes.XTickLabel = {'Cross' 'Eye'};
                app.UIAxes.XLim = [0.5 2.5];                            
                
            case 'Gender'
                isM = contains(app.t1.Data.Gender, 'M');
                y1 = app.t1.Data{isM,2:end};
                y1err = std(mean(y1,2))/sqrt(size(y1,1));
                y1 = mean(mean(y1));
                
                isF = contains(app.t1.Data.Gender, 'F');
                y2 = app.t1.Data{isF,2:end};
                y2err = std(mean(y2,2))/sqrt(size(y2,1));
                y2 = mean(mean(y2));
                
                x = 1:2;
                y = [y1 y2];
                yerr = [y1err y2err];

                errorbar(app.UIAxes,x,y,yerr,'Color','k');
                app.UIAxes.XTick = 1:2;
                app.UIAxes.XTickLabel = {'Males' 'Females'};
                app.UIAxes.XLim = [0.5 2.5];
                               
        end

app.UIAxes.XLabel.String = fact;         

        end

        % Value changed function: PartnerCheckBox_2
        function PartnerCheckBox_2ValueChanged(app, event)
            app.corrPartn = app.PartnerCheckBox_2.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = [app.t2.Data{:,4} app.t2.Data{:,5}]; % Partner columns
                        y = mean(y,2);                          % Collapse
                    else
                        y = app.t2.Data{:,1};
                    end
            end


            x = [app.t1.Data{:,4} app.t1.Data{:,5}]; % Partner columns
            x = mean(x,2);                          % Collapse

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);
            
            if app.corrPartn == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: StrangerCheckBox_2
        function StrangerCheckBox_2ValueChanged(app, event)
            app.corrStrang = app.StrangerCheckBox_2.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = [app.t2.Data{:,2} app.t2.Data{:,3}]; % Stranger columns
                        y = mean(y,2);                          % Collapse
                    else
                        y = app.t2.Data{:,1};
                    end
            end


            x = [app.t1.Data{:,2} app.t1.Data{:,3}]; % Stranger columns
            x = mean(x,2);                          % Collapse

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);
            
            if app.corrStrang == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: CrossCheckBox
        function CrossCheckBoxValueChanged(app, event)
            app.corrCross = app.CrossCheckBox.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = [app.t2.Data{:,2} app.t2.Data{:,4}]; % Cross columns
                        y = mean(y,2);                          % Collapse
                    else
                        y = app.t2.Data{:,1};
                    end
            end
                    
                    
            x = [app.t1.Data{:,2} app.t1.Data{:,4}]; % Cross columns
            x = mean(x,2);                          % Collapse

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);
            
            if app.corrCross == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: EyeCheckBox
        function EyeCheckBoxValueChanged(app, event)
            app.corrEye = app.EyeCheckBox.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = [app.t2.Data{:,3} app.t2.Data{:,5}]; % Eye columns
                        y = mean(y,2);                          % Collapse
                    else
                        y = app.t2.Data{:,1};
                    end    
            end


            x = [app.t1.Data{:,3} app.t1.Data{:,5}]; % Eye columns
            x = mean(x,2);                          % Collapse

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
           
            mdl = fitlm(x,y);
            
            if app.corrEye == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: PartnerCrossCheckBox
        function PartnerCrossCheckBoxValueChanged(app, event)
            app.corrPartnCross = app.PartnerCrossCheckBox.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = app.t2.Data{:,4}; % Partner Cross
                    else
                        y = app.t2.Data{:,1};
                    end
            end
            
            x = app.t1.Data{:,4}; % Partner Cross

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);
 
            if app.corrPartnCross == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: PartnerEyeCheckBox
        function PartnerEyeCheckBoxValueChanged(app, event)
            app.corrPartnEye = app.PartnerEyeCheckBox.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = app.t2.Data{:,5}; % Partner Eye
                    else
                        y = app.t2.Data{:,1};
                    end
            end
            
            x = app.t1.Data{:,5}; % Partner Eye

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);
 
            if app.corrPartnEye == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: StrangerCrossCheckBox_2
        function StrangerCrossCheckBox_2ValueChanged(app, event)
            app.corrStrangCross = app.StrangerCrossCheckBox_2.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = app.t2.Data{:,2}; % Stranger Cross
                    else
                        y = app.t2.Data{:,1};
                    end
            end
            
            x = app.t1.Data{:,2}; % Stranger Cross

            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);

            if app.corrStrangCross == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: StrangerEyeCheckBox
        function StrangerEyeCheckBoxValueChanged(app, event)
            app.corrStrangEye = app.StrangerEyeCheckBox.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                    case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = app.t2.Data{:,3}; % Stranger Eye
                    else
                        y = app.t2.Data{:,1};
                    end
            end
            
            x = app.t1.Data{:,3}; % Stranger Eye

             % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
            
            mdl = fitlm(x,y);
            
            if app.corrStrangEye == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: OverallCheckBox
        function OverallCheckBoxValueChanged(app, event)
            app.corrOverall = app.OverallCheckBox.Value;
            app.toCorr = app.CorrelatewithDropDown.Value;
            
            
            switch app.toCorr
                case 'Stai Tratto'
                    y = app.t2.Data{:,1};
                case 'Stai Stato'
                    y = app.t2.Data{:,2};
                case 'Stai Stato DeltaP'
                    y = app.t2.Data{:,5};
                case 'Stai Stato DeltaS'
                    y = app.t2.Data{:,6};
                case 'DAS AffExpr'
                    y = app.t2.Data{:,2};
                case 'DAS Tot'
                    y = app.t2.Data{:,5};
                otherwise
                    if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                        y = [app.t2.Data{:,2} app.t2.Data{:,3} app.t2.Data{:,4} app.t2.Data{:,5}]; 
                        y = mean(y,2);
                    else
                        y = app.t2.Data{:,1};
                    end
            end
            
            
            x = [app.t1.Data{:,2} app.t1.Data{:,3} app.t1.Data{:,4} app.t1.Data{:,5}];    
            x = mean(x,2);                                                                  


            % Separate by Gender
             isM = categorical(app.UITable.Data.Gender) == 'M';
             isF = categorical(app.UITable.Data.Gender) == 'F';

            if app.corrM == 1 && app.corrF == 0
                        x = x(isM,:);
                        y = y(isM,:);
            elseif app.corrM == 0 && app.corrF == 1
                        x = x(isF,:);
                        y = y(isF,:);
            else
            end
             

            mdl = fitlm(x,y);
            
            if app.corrOverall == 1
                plot(app.UIAxes2_2,mdl)
                app.UIAxes2_2.Title.String = ['R_adj = ' num2str(mdl.Rsquared.Adjusted) '. p = ' num2str(mdl.Coefficients.pValue(2))];
                legend(app.UIAxes2_2,'off')
                app.UIAxes2_2.XLabel.String = app.MeasureDropDown.Value;
                app.UIAxes2_2.YLabel.String = app.CorrelatewithDropDown.Value;
                
            else
                cla(app.UIAxes2_2,"reset")
            end
        end

        % Value changed function: CorrelatewithDropDown
        function CorrelatewithDropDownValueChanged(app, event)
        app.toCorr = app.CorrelatewithDropDown.Value;
            if contains(app.toCorr, 'Stai')
                app.UITable_2.Data = evalin('base', 'stai');
                app.UITable_2.ColumnName = {'tratto'	'stato_baseline'	'stato_P'	'stato_S'	'deltaP'	'deltaS'};
            elseif contains(app.toCorr, 'DAS')
                app.UITable_2.Data = evalin('base', 'DAS');
                app.UITable_2.ColumnName = {'Consensus'	'AffExpr'	'Satisfaction'	'Cohesion'	'Tot'};
            else
                app.UITable_2.Data = evalin('base', app.toCorr);
                if ~contains(app.toCorr, 'SIAS') && ~contains(app.toCorr, 'STQ') && ~contains(app.toCorr, 'DAS')
                    app.UITable_2.ColumnName = {'Gender' 'StrangerCross' 'StrangerEye' 'PartnerCross' 'PartnerEye'};
                end
            end
        
            app.t2 = app.UITable_2;
            
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.c1 = app.StartEditField.Value;
            
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.c2 = app.EndEditField.Value;
            
        end

        % Value changed function: NormEDACheckBox
        function NormEDACheckBoxValueChanged(app, event)
            app.c4 = app.NormEDACheckBox.Value;

        end

        % Value changed function: NormVASCheckBox
        function NormVASCheckBoxValueChanged(app, event)
            app.c5 = app.NormVASCheckBox.Value;
            
        end

        % Value changed function: PlotSingCouplesCheckBox
        function PlotSingCouplesCheckBoxValueChanged(app, event)
            app.c7 = app.PlotSingCouplesCheckBox.Value;
            
        end

        % Value changed function: PlotAveragesCheckBox
        function PlotAveragesCheckBoxValueChanged(app, event)
            app.c8 = app.PlotAveragesCheckBox.Value;
            
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)

            
            app.CantplotLamp.Color = 'r'; 
            drawnow

            file = ['Physio_' num2str(app.c1) '_to_' num2str(app.c2) '_threshold_' num2str(app.c11) '.mat'];
            
            assignin('base', 'file', file);
           
          
            startSecs = app.c1;
            endSecs = app.c2;
            answerBadSubjs = app.c3;
            answerNormEDA = app.c4;
            answerNormVAS = app.c5;
            answerPlotQuart = app.c6; answerSaveQuart = app.c16;
            answerPlotCouples = app.c7; answerSaveCouples = app.c12;
            answerPlotMean = app.c8; answerSaveMeans = app.c13;
            answerPlotSing = app.c9; answerSaveSing = app.c15;
            answerPlotVASVAS = app.c10; answerSaveVASVAS = app.c14;
            answerSaveStats = app.c17;
            answerNormECG = app.c18;
            answerNormType = app.c19;
            answerRSP = app.c20;
          
            
            assignin('base', 'startSecs', startSecs);
            assignin('base', 'endSecs', endSecs);
            assignin('base', 'answerBadSubjs', answerBadSubjs);
            assignin('base', 'answerNormEDA', answerNormEDA);
            assignin('base', 'answerNormVAS', answerNormVAS);
            assignin('base', 'answerPlotQuart', answerPlotQuart);
            assignin('base', 'answerPlotCouples', answerPlotCouples);
            assignin('base', 'answerPlotMean', answerPlotMean);
            assignin('base', 'answerPlotSing', answerPlotSing);
            assignin('base', 'answerPlotVASVAS', answerPlotVASVAS);
            assignin('base', 'answerSaveCouples', answerSaveCouples);
            assignin('base', 'answerSaveMeans', answerSaveMeans);
            assignin('base', 'answerSaveSing', answerSaveSing);
            assignin('base', 'answerSaveVASVAS', answerSaveVASVAS);
            assignin('base', 'answerSaveQuart', answerSaveQuart);
            assignin('base', 'answerSaveStats', answerSaveStats);
            assignin('base', 'answerNormECG', answerNormECG);
            assignin('base', 'answerNormType', answerNormType);
            assignin('base', 'answerRSP', answerRSP);

            app.CantplotLamp.Color = 'y';
            drawnow

            evalin('base','G_R_Statistics_N_sec_GENDER')
            
            
            app.CantplotLamp.Color = 'g';    
            drawnow
            
        end

        % Value changed function: PlotsinglesubjectsCheckBox
        function PlotsinglesubjectsCheckBoxValueChanged(app, event)
            app.c9 = app.PlotsinglesubjectsCheckBox.Value;
            
        end

        % Value changed function: PlotcorrVASVASCheckBox
        function PlotcorrVASVASCheckBoxValueChanged(app, event)
            app.c10 = app.PlotcorrVASVASCheckBox.Value;
            
        end

        % Value changed function: BadSubjsEditField
        function BadSubjsEditFieldValueChanged(app, event)
            app.c3 = app.BadSubjsEditField.Value;
            app.c3 = string(app.c3);
            app.c3 = str2num(app.c3{1});
            
        end

        % Value changed function: PlotinQuartersDropDown
        function PlotinQuartersDropDownValueChanged(app, event)
            app.c6 = app.PlotinQuartersDropDown.Value;
            app.c6 = string(app.c6);
            
            if app.c6 == "None"
                app.c6 = "";
            else
            end
        end

        % Value changed function: EDAthresholdDropDown
        function EDAthresholdDropDownValueChanged(app, event)
            value = app.EDAthresholdDropDown.Value;
            switch value
                case '0.10 mS'
                   app.c11 = '010'; 
                case '0.05 mS'
                    app.c11 = '005';
                case '0.01 mS'
                    app.c11 = '001';
            end
        end

        % Value changed function: SaveCheckBox
        function SaveCheckBoxValueChanged(app, event)
            app.c12 = app.SaveCheckBox.Value;
            
        end

        % Value changed function: SaveCheckBox_5
        function SaveCheckBox_5ValueChanged(app, event)
            app.c13 = app.SaveCheckBox_5.Value;
         
        end

        % Value changed function: SaveCheckBox_4
        function SaveCheckBox_4ValueChanged(app, event)
            app.c14 = app.SaveCheckBox_4.Value;
            
        end

        % Value changed function: SaveCheckBox_3
        function SaveCheckBox_3ValueChanged(app, event)
            app.c15 = app.SaveCheckBox_3.Value;
            
        end

        % Value changed function: SaveCheckBox_2
        function SaveCheckBox_2ValueChanged(app, event)
            app.c16 = app.SaveCheckBox_2.Value;
            
        end

        % Value changed function: SaveStatsCheckBox
        function SaveStatsCheckBoxValueChanged(app, event)
            app.c17 = app.SaveStatsCheckBox.Value;
            
        end

        % Value changed function: MCheckBox
        function MCheckBoxValueChanged(app, event)
            app.corrM = app.MCheckBox.Value;
            
        end

        % Value changed function: FCheckBox
        function FCheckBoxValueChanged(app, event)
            app.corrF = app.FCheckBox.Value;

        end

        % Value changed function: NormECGCheckBox
        function NormECGCheckBoxValueChanged(app, event)
            app.c18 = app.NormECGCheckBox.Value;
            
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            app.c19 = app.NormalizationDropDown.Value;
            
        end

        % Value changed function: AnalyzeRespCheckBox
        function AnalyzeRespCheckBoxValueChanged(app, event)
            app.c20 = app.AnalyzeRespCheckBox.Value;
            
        end

        % Callback function
        function ByBlockCheckBoxValueChanged(app, event)
            app.c21 = app.ByBlockCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportAnalysisTab = uitab(app.TabGroup);
            app.ImportAnalysisTab.Title = 'Import & Analysis';
            app.OKButton_2 = uibutton(app.ImportAnalysisTab, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.AnalysisWindowPanel = uipanel(app.ImportAnalysisTab);
            app.AnalysisWindowPanel.Title = 'Analysis Window';
            app.StartEditFieldLabel = uilabel(app.AnalysisWindowPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.AnalysisWindowPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditField.Value = 999;
            app.EndEditFieldLabel = uilabel(app.AnalysisWindowPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.AnalysisWindowPanel, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditField.Value = 999;
            app.TransformNormalizePanel = uipanel(app.ImportAnalysisTab);
            app.TransformNormalizePanel.Title = 'Transform (Normalize)';
            app.NormEDACheckBox = uicheckbox(app.TransformNormalizePanel);
            app.NormEDACheckBox.ValueChangedFcn = createCallbackFcn(app, @NormEDACheckBoxValueChanged, true);
            app.NormEDACheckBox.Text = 'Norm EDA';
            app.NormVASCheckBox = uicheckbox(app.TransformNormalizePanel);
            app.NormVASCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormVASCheckBoxValueChanged, true);
            app.NormVASCheckBox.Text = 'Norm VAS';
            app.EDAthresholdDropDownLabel = uilabel(app.TransformNormalizePanel);
            app.EDAthresholdDropDownLabel.Text = 'EDA threshold';
            app.EDAthresholdDropDown = uidropdown(app.TransformNormalizePanel);
            app.EDAthresholdDropDown.Items = {'Choose threshold:', '0.01 mS', '0.05 mS', '0.010 mS'};
            app.EDAthresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @EDAthresholdDropDownValueChanged, true);
            app.EDAthresholdDropDown.Value = 'Choose threshold:';
            app.NormECGCheckBox = uicheckbox(app.TransformNormalizePanel);
            app.NormECGCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormECGCheckBoxValueChanged, true);
            app.NormECGCheckBox.Text = 'Norm ECG';
            app.NormalizationDropDownLabel = uilabel(app.TransformNormalizePanel);
            app.NormalizationDropDownLabel.Text = 'Normalization: ';
            app.NormalizationDropDown = uidropdown(app.TransformNormalizePanel);
            app.NormalizationDropDown.Items = {'NO normalization', 'minMax', 'z-score'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'NO normalization';
            app.PlotsinseparatewindowsPanel = uipanel(app.ImportAnalysisTab);
            app.PlotsinseparatewindowsPanel.Title = 'Plots (in separate windows)';
            app.PlotSingCouplesCheckBox = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.PlotSingCouplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSingCouplesCheckBoxValueChanged, true);
            app.PlotSingCouplesCheckBox.Text = 'Plot Sing Couples';
            app.PlotcorrVASVASCheckBox = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.PlotcorrVASVASCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotcorrVASVASCheckBoxValueChanged, true);
            app.PlotcorrVASVASCheckBox.Text = 'Plot corr. VAS-VAS';
            app.PlotAveragesCheckBox = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.PlotAveragesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotAveragesCheckBoxValueChanged, true);
            app.PlotAveragesCheckBox.Text = 'Plot Averages';
            app.SaveCheckBox = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBoxValueChanged, true);
            app.SaveCheckBox.Text = 'Save';
            app.PlotsinglesubjectsCheckBox = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.PlotsinglesubjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotsinglesubjectsCheckBoxValueChanged, true);
            app.PlotsinglesubjectsCheckBox.Text = 'Plot single subjects';
            app.SaveCheckBox_2 = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.SaveCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBox_2ValueChanged, true);
            app.SaveCheckBox_2.Text = 'Save';
            app.SaveCheckBox_3 = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.SaveCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBox_3ValueChanged, true);
            app.SaveCheckBox_3.Text = 'Save';
            app.SaveCheckBox_4 = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.SaveCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBox_4ValueChanged, true);
            app.SaveCheckBox_4.Text = 'Save';
            app.SaveCheckBox_5 = uicheckbox(app.PlotsinseparatewindowsPanel);
            app.SaveCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBox_5ValueChanged, true);
            app.SaveCheckBox_5.Text = 'Save';
            app.PlotinQuartersDropDownLabel = uilabel(app.PlotsinseparatewindowsPanel);
            app.PlotinQuartersDropDownLabel.Text = 'Plot in Quarters';
            app.PlotinQuartersDropDown = uidropdown(app.PlotsinseparatewindowsPanel);
            app.PlotinQuartersDropDown.Items = {'None', 'VAS', 'EDA_Mean', 'PeakNumber', 'MeanPeakProminence', 'MeanPeakWidth', 'pLF', 'pHF', 'rpLF', 'rpHF', 'LF_HF_ratio'};
            app.PlotinQuartersDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotinQuartersDropDownValueChanged, true);
            app.PlotinQuartersDropDown.Value = 'None';
            app.SaveStatsCheckBox = uicheckbox(app.ImportAnalysisTab);
            app.SaveStatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveStatsCheckBoxValueChanged, true);
            app.SaveStatsCheckBox.Text = 'Save Stats';
            app.CantplotLamp = uilamp(app.ImportAnalysisTab);
            app.BadSubjsEditFieldLabel = uilabel(app.ImportAnalysisTab);
            app.BadSubjsEditFieldLabel.Text = 'Bad Subjs';
            app.BadSubjsEditField = uieditfield(app.ImportAnalysisTab, 'text');
            app.BadSubjsEditField.ValueChangedFcn = createCallbackFcn(app, @BadSubjsEditFieldValueChanged, true);
            app.EntervectorLabel = uilabel(app.ImportAnalysisTab);
            app.EntervectorLabel.Text = '(Enter vector)';
            app.AnalyzeRespCheckBox = uicheckbox(app.ImportAnalysisTab);
            app.AnalyzeRespCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeRespCheckBoxValueChanged, true);
            app.AnalyzeRespCheckBox.Text = 'Analyze Resp';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.UIAxes_3 = uiaxes(app.PlotsTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2_2 = uiaxes(app.PlotsTab);
            title(app.UIAxes2_2, 'Correlation')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes_2 = uiaxes(app.PlotsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.PlotsTab);
            title(app.UIAxes2, 'Double interaction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.PlotsTab);
            title(app.UIAxes, 'Main effect')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SeparategraphsDropDownLabel = uilabel(app.PlotsTab);
            app.SeparategraphsDropDownLabel.Text = 'Separate graphs';
            app.SeparategraphsDropDown = uidropdown(app.PlotsTab);
            app.SeparategraphsDropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.SeparategraphsDropDown.Value = 'Other';
            app.XaxisDropDownLabel = uilabel(app.PlotsTab);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.PlotsTab);
            app.XaxisDropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.XaxisDropDown.Value = 'Other';
            app.Factor2DropDownLabel = uilabel(app.PlotsTab);
            app.Factor2DropDownLabel.Text = 'Factor 2';
            app.Factor2DropDown = uidropdown(app.PlotsTab);
            app.Factor2DropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.Factor2DropDown.Value = 'Sguardo';
            app.FactorDropDownLabel = uilabel(app.PlotsTab);
            app.FactorDropDownLabel.Text = 'Factor';
            app.FactorDropDown = uidropdown(app.PlotsTab);
            app.FactorDropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.FactorDropDown.ValueChangedFcn = createCallbackFcn(app, @FactorDropDownValueChanged, true);
            app.FactorDropDown.Value = 'Other';
            app.MeasureDropDown_4Label = uilabel(app.PlotsTab);
            app.MeasureDropDown_4Label.Text = 'Measure';
            app.MeasureDropDown = uidropdown(app.PlotsTab);
            app.MeasureDropDown.Items = {'VAS', 'EDA_Mean', 'PeakNumber', 'MeanPeakProminence', 'MeanPeakWidth', 'pLF', 'pHF', 'rpLF', 'rpHF', 'LF_HF_ratio', 'BPM', 'SDNN', 'RMSSD', 'pNN50'};
            app.MeasureDropDown.Value = 'VAS';
            app.OKButton = uibutton(app.PlotsTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MainPanel = uipanel(app.PlotsTab);
            app.MainPanel.Title = 'Main';
            app.CrossCheckBox = uicheckbox(app.MainPanel);
            app.CrossCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossCheckBoxValueChanged, true);
            app.CrossCheckBox.Text = 'Cross';
            app.EyeCheckBox = uicheckbox(app.MainPanel);
            app.EyeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EyeCheckBoxValueChanged, true);
            app.EyeCheckBox.Text = 'Eye';
            app.PartnerCheckBox_2 = uicheckbox(app.MainPanel);
            app.PartnerCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PartnerCheckBox_2ValueChanged, true);
            app.PartnerCheckBox_2.Text = 'Partner';
            app.StrangerCheckBox_2 = uicheckbox(app.MainPanel);
            app.StrangerCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @StrangerCheckBox_2ValueChanged, true);
            app.StrangerCheckBox_2.Text = 'Stranger';
            app.InteractionsPanel = uipanel(app.PlotsTab);
            app.InteractionsPanel.Title = 'Interactions';
            app.PartnerCrossCheckBox = uicheckbox(app.InteractionsPanel);
            app.PartnerCrossCheckBox.ValueChangedFcn = createCallbackFcn(app, @PartnerCrossCheckBoxValueChanged, true);
            app.PartnerCrossCheckBox.Text = 'Partner Cross';
            app.PartnerEyeCheckBox = uicheckbox(app.InteractionsPanel);
            app.PartnerEyeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PartnerEyeCheckBoxValueChanged, true);
            app.PartnerEyeCheckBox.Text = 'Partner Eye';
            app.StrangerCrossCheckBox_2 = uicheckbox(app.InteractionsPanel);
            app.StrangerCrossCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @StrangerCrossCheckBox_2ValueChanged, true);
            app.StrangerCrossCheckBox_2.Text = 'Stranger Cross';
            app.StrangerEyeCheckBox = uicheckbox(app.InteractionsPanel);
            app.StrangerEyeCheckBox.ValueChangedFcn = createCallbackFcn(app, @StrangerEyeCheckBoxValueChanged, true);
            app.StrangerEyeCheckBox.Text = 'Stranger Eye';
            app.CorrelatewithDropDown = uidropdown(app.PlotsTab);
            app.CorrelatewithDropDown.Items = {'VAS', 'EDA_Mean', 'PeakNumber', 'MeanPeakProminence', 'MeanPeakWidth', 'Stai Tratto', 'Stai Stato', 'Stai Stato DeltaP', 'Stai Stato DeltaS', 'SIAS', 'STQ', 'DAS AffExpr', 'DAS Tot', 'pLF', 'pHF', 'rpLF', 'rpHF', 'LF_HF_ratio', 'BPM', 'SDNN', 'RMSSD', 'pNN50'};
            app.CorrelatewithDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrelatewithDropDownValueChanged, true);
            app.CorrelatewithDropDown.Value = 'EDA_Mean';
            app.OverallCheckBox = uicheckbox(app.PlotsTab);
            app.OverallCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverallCheckBoxValueChanged, true);
            app.OverallCheckBox.Text = 'Overall';
            app.MCheckBox = uicheckbox(app.PlotsTab);
            app.MCheckBox.ValueChangedFcn = createCallbackFcn(app, @MCheckBoxValueChanged, true);
            app.MCheckBox.Text = 'M';
            app.FCheckBox = uicheckbox(app.PlotsTab);
            app.FCheckBox.ValueChangedFcn = createCallbackFcn(app, @FCheckBoxValueChanged, true);
            app.FCheckBox.Text = 'F';
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.UITable = uitable(app.Tables);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Tables);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.We3GiversLabel = uilabel(app.UIFigure);
            app.We3GiversLabel.Text = 'We <3 Givers';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1PLOTTERb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end