classdef app1b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GIVERRECEIVERSETTINGSLabel    matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        CleanandanalyzeTab            matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        BadsubjectsspaceseparatedvaluesEditField  matlab.ui.control.NumericEditField
        BadsubjectsspaceseparatedvaluesEditFieldLabel  matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        ChoosetonormalizevaluesPanel  matlab.ui.container.Panel
        NormalizeEDACheckBox          matlab.ui.control.CheckBox
        NormalizeECGCheckBox          matlab.ui.control.CheckBox
        NormalizeVASCheckBox          matlab.ui.control.CheckBox
        NormalizeRSPCheckBox          matlab.ui.control.CheckBox
        SelectwindowtorunstatsPanel   matlab.ui.container.Panel
        EndtimeEditField              matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel         matlab.ui.control.Label
        StarttimeEditField            matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel       matlab.ui.control.Label
        StatsTab                      matlab.ui.container.Tab
        PlotsTab                      matlab.ui.container.Tab
        DoubleInteractionsPanel       matlab.ui.container.Panel
        Factor2DropDown               matlab.ui.control.DropDown
        Factor2DropDownLabel          matlab.ui.control.Label
        MeasureDropDown_2             matlab.ui.control.DropDown
        MeasureDropDown_2Label        matlab.ui.control.Label
        Factor1DropDown               matlab.ui.control.DropDown
        Factor1DropDownLabel          matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        MainEffectsPanel              matlab.ui.container.Panel
        MeasureDropDown               matlab.ui.control.DropDown
        MeasureDropDownLabel          matlab.ui.control.Label
        FactorDropDown                matlab.ui.control.DropDown
        FactorDropDownLabel           matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        endSecs
        startSecs
        answerNormVAS
        answerNormEDA
        answerBadSubjs
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EndtimeEditField
        function EndtimeEditFieldValueChanged(app, event)
            value = app.EndtimeEditField.Value;
                assignin('base','endSecs',value);
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            value = app.StarttimeEditField.Value;
                assignin('base','startSecs',value);
        end

        % Value changed function: NormalizeVASCheckBox
        function NormalizeVASCheckBoxValueChanged(app, event)
            value = app.NormalizeVASCheckBox.Value;
                assignin('base','answerNormVAS', value);
        end

        % Value changed function: NormalizeEDACheckBox
        function NormalizeEDACheckBoxValueChanged(app, event)
            value = app.NormalizeEDACheckBox.Value;
            assignin('base','answerNormEDA', value);
        end

        % Value changed function: 
        % BadsubjectsspaceseparatedvaluesEditField
        function BadsubjectsspaceseparatedvaluesEditFieldValueChanged(app, event)
            value = app.BadsubjectsspaceseparatedvaluesEditField.Value;
            assignin('base','answerBadSubjs', value);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            run('G_R_Statistics_N_sec_GENDER'); % rember to transorfm the scipt into a function!!

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CleanandanalyzeTab = uitab(app.TabGroup);
            app.CleanandanalyzeTab.Title = 'Clean and analyze';
            app.SelectwindowtorunstatsPanel = uipanel(app.CleanandanalyzeTab);
            app.SelectwindowtorunstatsPanel.Title = 'Select window to run stats';
            app.StarttimeEditFieldLabel = uilabel(app.SelectwindowtorunstatsPanel);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.SelectwindowtorunstatsPanel, 'numeric');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.StarttimeEditField.Value = 999;
            app.EndtimeEditFieldLabel = uilabel(app.SelectwindowtorunstatsPanel);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.SelectwindowtorunstatsPanel, 'numeric');
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeEditFieldValueChanged, true);
            app.EndtimeEditField.Value = 999;
            app.ChoosetonormalizevaluesPanel = uipanel(app.CleanandanalyzeTab);
            app.ChoosetonormalizevaluesPanel.Title = 'Choose to normalize values';
            app.NormalizeRSPCheckBox = uicheckbox(app.ChoosetonormalizevaluesPanel);
            app.NormalizeRSPCheckBox.Text = 'Normalize RSP';
            app.NormalizeVASCheckBox = uicheckbox(app.ChoosetonormalizevaluesPanel);
            app.NormalizeVASCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeVASCheckBoxValueChanged, true);
            app.NormalizeVASCheckBox.Text = 'Normalize VAS';
            app.NormalizeECGCheckBox = uicheckbox(app.ChoosetonormalizevaluesPanel);
            app.NormalizeECGCheckBox.Text = 'Normalize ECG';
            app.NormalizeEDACheckBox = uicheckbox(app.ChoosetonormalizevaluesPanel);
            app.NormalizeEDACheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeEDACheckBoxValueChanged, true);
            app.NormalizeEDACheckBox.Text = 'Normalize EDA';
            app.RunButton = uibutton(app.CleanandanalyzeTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BadsubjectsspaceseparatedvaluesEditFieldLabel = uilabel(app.CleanandanalyzeTab);
            app.BadsubjectsspaceseparatedvaluesEditFieldLabel.Text = 'Bad subjects (space separated values)';
            app.BadsubjectsspaceseparatedvaluesEditField = uieditfield(app.CleanandanalyzeTab, 'numeric');
            app.BadsubjectsspaceseparatedvaluesEditField.ValueChangedFcn = createCallbackFcn(app, @BadsubjectsspaceseparatedvaluesEditFieldValueChanged, true);
            app.UITable = uitable(app.CleanandanalyzeTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.MainEffectsPanel = uipanel(app.PlotsTab);
            app.MainEffectsPanel.Title = 'Main Effects';
            app.UIAxes = uiaxes(app.MainEffectsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FactorDropDownLabel = uilabel(app.MainEffectsPanel);
            app.FactorDropDownLabel.Text = 'Factor';
            app.FactorDropDown = uidropdown(app.MainEffectsPanel);
            app.FactorDropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.FactorDropDown.Value = 'Other';
            app.MeasureDropDownLabel = uilabel(app.MainEffectsPanel);
            app.MeasureDropDownLabel.Text = 'Measure';
            app.MeasureDropDown = uidropdown(app.MainEffectsPanel);
            app.MeasureDropDown.Items = {'VAS', 'EDA_Mean', 'PeakNumber', 'MeanPeakAmplitude', 'STAI Tratto', 'Stai Stato', 'Stai Stato DeltaP', 'Stai Stato DeltaS'};
            app.MeasureDropDown.Value = 'VAS';
            app.DoubleInteractionsPanel = uipanel(app.PlotsTab);
            app.DoubleInteractionsPanel.Title = 'Double Interactions';
            app.UIAxes_2 = uiaxes(app.DoubleInteractionsPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Factor1DropDownLabel = uilabel(app.DoubleInteractionsPanel);
            app.Factor1DropDownLabel.Text = 'Factor 1';
            app.Factor1DropDown = uidropdown(app.DoubleInteractionsPanel);
            app.Factor1DropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.Factor1DropDown.Value = 'Other';
            app.MeasureDropDown_2Label = uilabel(app.DoubleInteractionsPanel);
            app.MeasureDropDown_2Label.Text = 'Measure';
            app.MeasureDropDown_2 = uidropdown(app.DoubleInteractionsPanel);
            app.MeasureDropDown_2.Items = {'VAS', 'EDA_Mean', 'PeakNumber', 'MeanPeakAmplitude', 'STAI Tratto', 'Stai Stato', 'Stai Stato DeltaP', 'Stai Stato DeltaS'};
            app.MeasureDropDown_2.Value = 'VAS';
            app.Factor2DropDownLabel = uilabel(app.DoubleInteractionsPanel);
            app.Factor2DropDownLabel.Text = 'Factor 2';
            app.Factor2DropDown = uidropdown(app.DoubleInteractionsPanel);
            app.Factor2DropDown.Items = {'Other', 'Sguardo', 'Gender'};
            app.Factor2DropDown.Value = 'Other';
            app.GIVERRECEIVERSETTINGSLabel = uilabel(app.UIFigure);
            app.GIVERRECEIVERSETTINGSLabel.Text = 'GIVER RECEIVER SETTINGS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end